classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Tab1                           matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        TransferFunctionPanel          matlab.ui.container.Panel
        InputGridLayout                matlab.ui.container.GridLayout
        CoefficientsLabel              matlab.ui.control.Label
        TransferFunctionNumEditText    matlab.ui.control.EditField
        TransferFunctionDenomEditText  matlab.ui.control.EditField
        TransferFunctionLabel          matlab.ui.control.Label
        StateSpacePanel                matlab.ui.container.Panel
        OutputGridLayout               matlab.ui.container.GridLayout
        ControllableLabel              matlab.ui.control.Label
        ObservableLabel                matlab.ui.control.Label
        PhaseLabel_2                   matlab.ui.control.Label
        JordanLabel                    matlab.ui.control.Label
        Controllable_ALabel            matlab.ui.control.Label
        Controllable_BLabel            matlab.ui.control.Label
        Controllable_CLabel            matlab.ui.control.Label
        Controllable_DLabel            matlab.ui.control.Label
        Observable_ALabel              matlab.ui.control.Label
        Observable_BLabel              matlab.ui.control.Label
        Observable_CLabel              matlab.ui.control.Label
        Observable_DLabel              matlab.ui.control.Label
        Phase_ALabel                   matlab.ui.control.Label
        Phase_BLabel                   matlab.ui.control.Label
        Phase_CLabel                   matlab.ui.control.Label
        Phase_DLabel                   matlab.ui.control.Label
        Jordan_ALabel                  matlab.ui.control.Label
        Jordan_BLabel                  matlab.ui.control.Label
        Jordan_CLabel                  matlab.ui.control.Label
        Jordan_DLabel                  matlab.ui.control.Label
        DiagonalLabel                  matlab.ui.control.Label
        Diagonal_ALabel                matlab.ui.control.Label
        Diagonal_BLabel                matlab.ui.control.Label
        Diagonal_CLabel                matlab.ui.control.Label
        Diagonal_DLabel                matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        Observability_Label            matlab.ui.control.Label
        Controllability_Label          matlab.ui.control.Label
        StepResponsePlot               matlab.ui.control.UIAxes
        PoleZeroPlot                   matlab.ui.control.UIAxes
        StateSpaceTransferFunctionTab  matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        StateSpacePanel_2              matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        AEditFieldLabel                matlab.ui.control.Label
        AEditField                     matlab.ui.control.EditField
        BEditFieldLabel                matlab.ui.control.Label
        BEditField                     matlab.ui.control.EditField
        CEditFieldLabel                matlab.ui.control.Label
        CEditField                     matlab.ui.control.EditField
        DLabel                         matlab.ui.control.Label
        DEditField                     matlab.ui.control.EditField
        TransferFunctionPanel_2        matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        TransferFunctionLabel_2        matlab.ui.control.Label
        StepResponsePlot_2             matlab.ui.control.UIAxes
        PoleZeroPlot_2                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % This is where you define global variables
    end
    
    methods (Access = private)
        function UpdateUI_TF_SS(app, numCoeff, denomCoeff)
            % This function updates the UI of the transfer-function-to-state-space tab 
            % according to the inputs
            
            % It takes 2 character vectors as inputs: one for the numerator
            % coefficients, one for the denominator coefficients 
            
            % Convert character vectors to numeric vectors 
            [num, numRet] = str2num(numCoeff);
            [denom, denomRet] = str2num(denomCoeff);
            
            if numRet == 1 && denomRet == 1 % if the character vectors were converted successfully
                % Run the command "tf(num, denom)" and capture the output 
                sys_char = evalc('tf(num, denom)');
                
                % split the captured output by line
                sys_cell = splitlines(sys_char);
                
                if length(num) == 1 && length(denom) == 1 % if the the transfer function is static gain
                    % Remove the unneccessary lines from the output
                    % The actual transfer function are in lines 4
                    app.TransferFunctionLabel.Text = sys_cell(4);
                else
                    % Remove the unneccessary lines from the output
                    % The actual transfer function are in lines 4, 5, and 6
                    app.TransferFunctionLabel.Text = sys_cell(4:6);
                end
                
                if length(num) <= length(denom) % if the transfer function is proper
                % Plot step response
                    [y,t] = step(tf(num,denom));
                    plot(app.StepResponsePlot,t,y);
                    
                    % Set the minimum and maximum values on the X and Y axes
                    app.StepResponsePlot.XLim = [0 t(end)];
                    app.StepResponsePlot.YLim = [0 max(y)*1.05];
                    
                    % Set the x-ticks
                    xTick = round(t(1:floor(length(t)/4):length(t)), 2);
                    app.StepResponsePlot.XTick = xTick;
                    app.StepResponsePlot.XTickLabel = xTick;
                    
                    % Set the y-ticks
                    yTick = round(0:max(y)/4:max(y), 2);
                    app.StepResponsePlot.YTick = yTick;
                    app.StepResponsePlot.YTickLabel = yTick;
                    
                % Plot poles and zeros
                    [z,p,~] = tf2zp(num,denom);
                    plot(app.PoleZeroPlot,real(z),imag(z),'bo');
                    hold(app.PoleZeroPlot, 'on');
                    plot(app.PoleZeroPlot,real(p),imag(p),'rx');
                    hold(app.PoleZeroPlot, 'off');
                    
                    % Set the minimum and maximum values on the real and imaginary axes
                    xMax = max(abs([z,real(p)']))*1.2;
                    yMax = max(abs(imag(p)))*1.2;
                    
                    g = xMax == 0;
                    h = isempty(xMax);
                    
                    if xMax == 0
                        xMax = 1;
                    end
                    
                    if yMax == 0
                        yMax = 1;
                    end
                    
                    if isempty(xMax)
                        xMax = 1;
                    end
                    
                    if isempty(yMax)
                        yMax = 1;
                    end
                    
                    app.PoleZeroPlot.XLim = [-xMax xMax];
                    app.PoleZeroPlot.YLim = [-yMax yMax];
                    
                    % Set the real-ticks
                    xTick = round(-xMax:xMax/4:xMax, 2);
                    app.PoleZeroPlot.XTick = xTick;
                    app.PoleZeroPlot.XTickLabel = xTick;
                    
                    % Set the imaginary-ticks
                    yTick = round(-yMax:yMax/4:yMax, 2);
                    app.PoleZeroPlot.YTick = yTick;
                    app.PoleZeroPlot.YTickLabel = yTick;
                    
                    % Place the real and imaginary axes on the origin
                    app.PoleZeroPlot.XAxisLocation = 'Origin';
                    app.PoleZeroPlot.YAxisLocation = 'Origin';
                else
                % Plot step response
                    plot(app.StepResponsePlot,[0],[0]);
                    
                    % Set the minimum and maximum values on the X and Y axes
                    app.StepResponsePlot.XLim = [0 1];
                    app.StepResponsePlot.YLim = [0 1];
                    
                    % Set the x-ticks
                    app.StepResponsePlot.XTick = [0,0.5,1];
                    app.StepResponsePlot.XTickLabel = [0,0.5,1];
                    
                    % Set the y-ticks
                    app.StepResponsePlot.YTick = [0,0.5,1];
                    app.StepResponsePlot.YTickLabel = [0,0.5,1];
                    
                % Plot poles and zeros
                    plot(app.PoleZeroPlot,[0],[0]);
                    
                    % Set the minimum and maximum values on the real and imaginary axes
                    app.PoleZeroPlot.XLim = [0 1];
                    app.PoleZeroPlot.YLim = [0 1];
                    
                    % Set the real-ticks
                    app.PoleZeroPlot.XTick = [0,0.5,1];
                    app.PoleZeroPlot.XTickLabel = [0,0.5,1];
                    
                    % Set the imaginary-ticks
                    app.PoleZeroPlot.YTick = [0,0.5,1];
                    app.PoleZeroPlot.YTickLabel = [0,0.5,1];
                    
                    % Place the real and imaginary axes on the origin
                    app.PoleZeroPlot.XAxisLocation = 'Origin';
                    app.PoleZeroPlot.YAxisLocation = 'Origin';
                end
                
                % Controller cannonical form
                [A_CCF, B_CCF, C_CCF, D_CCF, ret_CCF] = CCF(num, denom);
                
                if ret_CCF == 1
                    app.Controllable_ALabel.Text = ['A = ', mat2str(round(A_CCF,3))];
                    app.Controllable_BLabel.Text = ['B = ', mat2str(round(B_CCF,3))];
                    app.Controllable_CLabel.Text = ['C = ', mat2str(round(C_CCF,3))];
                    app.Controllable_DLabel.Text = ['D = ', mat2str(round(D_CCF,3))];
                else
                    app.Controllable_ALabel.Text = 'A =';
                    app.Controllable_BLabel.Text = 'B =';
                    app.Controllable_CLabel.Text = 'C =';
                    app.Controllable_DLabel.Text = 'D =';
                end
                
                % Observer cannonical form
                [A_OCF, B_OCF, C_OCF, D_OCF, ret_OCF] = OCF(num, denom);
                
                if ret_OCF == 1
                    app.Observable_ALabel.Text = ['A = ', mat2str(round(A_OCF,3))];
                    app.Observable_BLabel.Text = ['B = ', mat2str(round(B_OCF,3))];
                    app.Observable_CLabel.Text = ['C = ', mat2str(round(C_OCF,3))];
                    app.Observable_DLabel.Text = ['D = ', mat2str(round(D_OCF,3))];
                else
                    app.Observable_ALabel.Text = 'A =';
                    app.Observable_BLabel.Text = 'B =';
                    app.Observable_CLabel.Text = 'C =';
                    app.Observable_DLabel.Text = 'D =';
                end
                
                % Phase variable form
                [A_PVF, B_PVF, C_PVF, D_PVF, ret_PVF] = PVF(num, denom);
                
                if ret_PVF == 1
                    app.Phase_ALabel.Text = ['A = ', mat2str(round(A_PVF,3))];
                    app.Phase_BLabel.Text = ['B = ', mat2str(round(B_PVF,3))];
                    app.Phase_CLabel.Text = ['C = ', mat2str(round(C_PVF,3))];
                    app.Phase_DLabel.Text = ['D = ', mat2str(round(D_PVF,3))];
                else
                    app.Phase_ALabel.Text = 'A =';
                    app.Phase_BLabel.Text = 'B =';
                    app.Phase_CLabel.Text = 'C =';
                    app.Phase_DLabel.Text = 'D =';
                end
                
                % Jordan form
                [A_JCF, B_JCF, C_JCF, D_JCF, ret_JCF] = JCF(num, denom);
                
                if ret_JCF == 1
                    app.Jordan_ALabel.Text = ['A = ', mat2str(round(A_JCF,3))];
                    app.Jordan_BLabel.Text = ['B = ', mat2str(round(B_JCF,3))];
                    app.Jordan_CLabel.Text = ['C = ', mat2str(round(C_JCF,3))];
                    app.Jordan_DLabel.Text = ['D = ', mat2str(round(D_JCF,3))];
                else
                    app.Jordan_ALabel.Text = 'A =';
                    app.Jordan_BLabel.Text = 'B =';
                    app.Jordan_CLabel.Text = 'C =';
                    app.Jordan_DLabel.Text = 'D =';
                end
                
                % Diagonal form
                 [A_DCF, B_DCF, C_DCF, D_DCF, ret_DCF] = DCF(num, denom);
                
                if ret_DCF == 1
                    app.Diagonal_ALabel.Text = ['A = ', mat2str(round(A_DCF,3))];
                    app.Diagonal_BLabel.Text = ['B = ', mat2str(round(B_DCF,3))];
                    app.Diagonal_CLabel.Text = ['C = ', mat2str(round(C_DCF,3))];
                    app.Diagonal_DLabel.Text = ['D = ', mat2str(round(D_DCF,3))];
                else
                    app.Diagonal_ALabel.Text = 'A =';
                    app.Diagonal_BLabel.Text = 'B =';
                    app.Diagonal_CLabel.Text = 'C =';
                    app.Diagonal_DLabel.Text = 'D =';
                end
                
                % Controllability
                if(rank(ctrb(A_CCF,B_CCF)) == rank(A_CCF))
                    app.Controllability_Label.Visible = 1;
                else
                    app.Controllability_Label.Visible = 0;
                end
                
                % Observability
                if(rank(obsv(A_CCF, C_CCF)) == rank(A_CCF))
                    app.Observability_Label.Visible = 1;
                else
                    app.Observability_Label.Visible = 0;
                end
            else
                app.TransferFunctionLabel.Text = 'Invalid';
                app.Observability_Label.Visible = 0;
                app.Controllability_Label.Visible = 0;
                
            % Plot step response
                plot(app.StepResponsePlot,[0],[0]);
                
                % Set the minimum and maximum values on the X and Y axes
                app.StepResponsePlot.XLim = [0 1];
                app.StepResponsePlot.YLim = [0 1];
                
                % Set the x-ticks
                app.StepResponsePlot.XTick = [0,0.5,1];
                app.StepResponsePlot.XTickLabel = [0,0.5,1];
                
                % Set the y-ticks
                app.StepResponsePlot.YTick = [0,0.5,1];
                app.StepResponsePlot.YTickLabel = [0,0.5,1];
                
            % Plot poles and zeros
                plot(app.PoleZeroPlot,[0],[0]);
                
                % Set the minimum and maximum values on the real and imaginary axes
                app.PoleZeroPlot.XLim = [0 1];
                app.PoleZeroPlot.YLim = [0 1];
                
                % Set the real-ticks
                app.PoleZeroPlot.XTick = [0,0.5,1];
                app.PoleZeroPlot.XTickLabel = [0,0.5,1];
                
                % Set the imaginary-ticks
                app.PoleZeroPlot.YTick = [0,0.5,1];
                app.PoleZeroPlot.YTickLabel = [0,0.5,1];
                
                % Place the real and imaginary axes on the origin
                app.PoleZeroPlot.XAxisLocation = 'Origin';
                app.PoleZeroPlot.YAxisLocation = 'Origin';
            end
        end
    
        function UpdateUI_SS_TF(app, AChar, BChar, CChar, DChar)
            % This function updates the UI of the state-space-to-transfer-function tab 
            % according to the inputs
            
            % It takes 4 character vectors as inputs: one for each of the
            % matrices A, B, C, and D
            
            % Convert character vectors to numeric vectors 
            [A, ARet] = str2num(AChar);
            [B, BRet] = str2num(BChar);
            [C, CRet] = str2num(CChar);
            [D, DRet] = str2num(DChar);
            
            [A_rows,A_cols] = size(A);
            [B_rows,B_cols] = size(B);
            [C_rows,C_cols] = size(C);
            [D_rows,D_cols] = size(D);
            
            if ARet == 1 && BRet == 1 && CRet == 1 && DRet == 1 &&... % if the character vectors were converted successfully
                A_rows == A_cols && A_rows == B_rows && A_rows == C_cols &&...
                B_cols == D_cols && C_rows == D_rows % if the character vectors were converted successfully
            % Transfer function
                % Get the numerator and denominator coefficients of the transfer function
                [num,denom] = ss2tf(A,B,C,D);
                
                % Run the command "tf(num, denom)" and capture the output 
                sys_char = evalc('tf(num, denom)');
                
                % split the captured output by line
                sys_cell = splitlines(sys_char);
                
                if length(num) == 1 && length(denom) == 1 % if the the transfer function is static gain
                    % Remove the unneccessary lines from the output
                    % The actual transfer function are in lines 4
                    app.TransferFunctionLabel_2.Text = sys_cell(4);
                else
                    % Remove the unneccessary lines from the output
                    % The actual transfer function are in lines 4, 5, and 6
                    app.TransferFunctionLabel_2.Text = sys_cell(4:6);
                end
                
            % Plot step response
                [y,t] = step(tf(num,denom));
                plot(app.StepResponsePlot_2,t,y);
                
                % Set the minimum and maximum values on the X and Y axes
                app.StepResponsePlot_2.XLim = [0 t(end)];
                app.StepResponsePlot_2.YLim = [0 max(y)*1.05];
                
                % Set the x-ticks
                xTick = round(t(1:floor(length(t)/4):length(t)), 2);
                app.StepResponsePlot_2.XTick = xTick;
                app.StepResponsePlot_2.XTickLabel = xTick;
                
                % Set the y-ticks
                yTick = round(0:max(y)/4:max(y), 2);
                app.StepResponsePlot_2.YTick = yTick;
                app.StepResponsePlot_2.YTickLabel = yTick;
                
            % Plot poles and zeros
                [z,p,~] = tf2zp(num,denom);
                plot(app.PoleZeroPlot_2,real(z),imag(z),'bo');
                hold(app.PoleZeroPlot_2, 'on');
                plot(app.PoleZeroPlot_2,real(p),imag(p),'rx');
                hold(app.PoleZeroPlot_2, 'off');
                
                % Set the minimum and maximum values on the real and imaginary axes
                xMax = max(abs([z,real(p)']))*1.2;
                yMax = max(abs(imag(p)))*1.2;
                
                if xMax == 0
                    xMax = 1;
                end
                
                if yMax == 0
                    yMax = 1;
                end
                
                if isempty(xMax)
                    xMax = 1;
                end
                
                if isempty(yMax)
                    yMax = 1;
                end
                
                app.PoleZeroPlot_2.XLim = [-xMax xMax];
                app.PoleZeroPlot_2.YLim = [-yMax yMax];
                
                % Set the real-ticks
                xTick = round(-xMax:xMax/4:xMax, 2);
                app.PoleZeroPlot_2.XTick = xTick;
                app.PoleZeroPlot_2.XTickLabel = xTick;
                
                % Set the imaginary-ticks
                yTick = round(-yMax:yMax/4:yMax, 2);
                app.PoleZeroPlot_2.YTick = yTick;
                app.PoleZeroPlot_2.YTickLabel = yTick;
                
                % Place the real and imaginary axes on the origin
                app.PoleZeroPlot_2.XAxisLocation = 'Origin';
                app.PoleZeroPlot_2.YAxisLocation = 'Origin';
            else
                app.TransferFunctionLabel_2.Text = 'Invalid';
                
            % Plot step response
                plot(app.StepResponsePlot_2,[0],[0]);
                
                % Set the minimum and maximum values on the X and Y axes
                app.StepResponsePlot_2.XLim = [0 1];
                app.StepResponsePlot_2.YLim = [0 1];
                
                % Set the x-ticks
                app.StepResponsePlot_2.XTick = [0,0.5,1];
                app.StepResponsePlot_2.XTickLabel = [0,0.5,1];
                
                % Set the y-ticks
                app.StepResponsePlot_2.YTick = [0,0.5,1];
                app.StepResponsePlot_2.YTickLabel = [0,0.5,1];
                
            % Plot poles and zeros
                plot(app.PoleZeroPlot_2,[0],[0]);
                
                % Set the minimum and maximum values on the real and imaginary axes
                app.PoleZeroPlot_2.XLim = [0 1];
                app.PoleZeroPlot_2.YLim = [0 1];
                
                % Set the real-ticks
                app.PoleZeroPlot_2.XTick = [0,0.5,1];
                app.PoleZeroPlot_2.XTickLabel = [0,0.5,1];
                
                % Set the imaginary-ticks
                app.PoleZeroPlot_2.YTick = [0,0.5,1];
                app.PoleZeroPlot_2.YTickLabel = [0,0.5,1];
                
                % Place the real and imaginary axes on the origin
                app.PoleZeroPlot_2.XAxisLocation = 'Origin';
                app.PoleZeroPlot_2.YAxisLocation = 'Origin';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % This function runs once when the app first starts
        end

        % Value changing function: TransferFunctionNumEditText
        function TransferFunctionNumEditTextValueChanging(app, event)
            % This function runs when the value in the text field for
            % setting the numerator coefficients of the transfer function
            % changes
            
            % Update the UI
            app.UpdateUI_TF_SS(event.Value, app.TransferFunctionDenomEditText.Value);
        end

        % Value changing function: TransferFunctionDenomEditText
        function TransferFunctionDenomEditTextValueChanging(app, event)
            % This function runs when the value in the text field for
            % setting the denominator coefficients of the transfer function
            % changes
            
            % Update the UI
            app.UpdateUI_TF_SS(app.TransferFunctionNumEditText.Value, event.Value);
        end

        % Value changing function: AEditField
        function AEditFieldValueChanging(app, event)
            % This function runs when the value in the text field for
            % setting the A matrix of the state space changes
            
            % Update the UI
            app.UpdateUI_SS_TF(event.Value, app.BEditField.Value, app.CEditField.Value, app.DEditField.Value);
        end

        % Value changing function: BEditField
        function BEditFieldValueChanging(app, event)
            % This function runs when the value in the text field for
            % setting the A matrix of the state space changes
            
            % Update the UI
            app.UpdateUI_SS_TF(app.AEditField.Value, event.Value, app.CEditField.Value, app.DEditField.Value);
        end

        % Value changing function: CEditField
        function CEditFieldValueChanging(app, event)
            % This function runs when the value in the text field for
            % setting the A matrix of the state space changes
            
            % Update the UI
            app.UpdateUI_SS_TF(app.AEditField.Value, app.BEditField.Value, event.Value, app.DEditField.Value);
        end

        % Value changing function: DEditField
        function DEditFieldValueChanging(app, event)
            % This function runs when the value in the text field for
            % setting the A matrix of the state space changes
            
            % Update the UI
            app.UpdateUI_SS_TF(app.AEditField.Value, app.BEditField.Value, app.CEditField.Value, event.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Transfer Function - State Space';
            app.GridLayout = uigridlayout(app.Tab1);
            app.TransferFunctionPanel = uipanel(app.GridLayout);
            app.TransferFunctionPanel.Title = 'Transfer Function';
            app.InputGridLayout = uigridlayout(app.TransferFunctionPanel);
            app.CoefficientsLabel = uilabel(app.InputGridLayout);
            app.CoefficientsLabel.Text = 'Coefficients ';
            app.TransferFunctionNumEditText = uieditfield(app.InputGridLayout, 'text');
            app.TransferFunctionNumEditText.ValueChangingFcn = createCallbackFcn(app, @TransferFunctionNumEditTextValueChanging, true);
            app.TransferFunctionDenomEditText = uieditfield(app.InputGridLayout, 'text');
            app.TransferFunctionDenomEditText.ValueChangingFcn = createCallbackFcn(app, @TransferFunctionDenomEditTextValueChanging, true);
            app.TransferFunctionLabel = uilabel(app.InputGridLayout);
            app.TransferFunctionLabel.Text = '';
            app.StateSpacePanel = uipanel(app.GridLayout);
            app.StateSpacePanel.Title = 'State Space';
            app.OutputGridLayout = uigridlayout(app.StateSpacePanel);
            app.ControllableLabel = uilabel(app.OutputGridLayout);
            app.ControllableLabel.Text = {'Controllable'; 'Canonical Form'};
            app.ObservableLabel = uilabel(app.OutputGridLayout);
            app.ObservableLabel.Text = {'Observable'; 'Canonical Form'};
            app.PhaseLabel_2 = uilabel(app.OutputGridLayout);
            app.PhaseLabel_2.Text = {'Phase Variable'; 'Canonical Form'};
            app.JordanLabel = uilabel(app.OutputGridLayout);
            app.JordanLabel.Text = {'Jordan'; 'Canonical Form'};
            app.Controllable_ALabel = uilabel(app.OutputGridLayout);
            app.Controllable_ALabel.Text = 'A = ';
            app.Controllable_BLabel = uilabel(app.OutputGridLayout);
            app.Controllable_BLabel.Text = 'B = ';
            app.Controllable_CLabel = uilabel(app.OutputGridLayout);
            app.Controllable_CLabel.Text = 'C = ';
            app.Controllable_DLabel = uilabel(app.OutputGridLayout);
            app.Controllable_DLabel.Text = 'D = ';
            app.Observable_ALabel = uilabel(app.OutputGridLayout);
            app.Observable_ALabel.Text = 'A = ';
            app.Observable_BLabel = uilabel(app.OutputGridLayout);
            app.Observable_BLabel.Text = 'B = ';
            app.Observable_CLabel = uilabel(app.OutputGridLayout);
            app.Observable_CLabel.Text = 'C = ';
            app.Observable_DLabel = uilabel(app.OutputGridLayout);
            app.Observable_DLabel.Text = 'D = ';
            app.Phase_ALabel = uilabel(app.OutputGridLayout);
            app.Phase_ALabel.Text = 'A = ';
            app.Phase_BLabel = uilabel(app.OutputGridLayout);
            app.Phase_BLabel.Text = 'B = ';
            app.Phase_CLabel = uilabel(app.OutputGridLayout);
            app.Phase_CLabel.Text = 'C = ';
            app.Phase_DLabel = uilabel(app.OutputGridLayout);
            app.Phase_DLabel.Text = 'D = ';
            app.Jordan_ALabel = uilabel(app.OutputGridLayout);
            app.Jordan_ALabel.Text = 'A = ';
            app.Jordan_BLabel = uilabel(app.OutputGridLayout);
            app.Jordan_BLabel.Text = 'B = ';
            app.Jordan_CLabel = uilabel(app.OutputGridLayout);
            app.Jordan_CLabel.Text = 'C = ';
            app.Jordan_DLabel = uilabel(app.OutputGridLayout);
            app.Jordan_DLabel.Text = 'D = ';
            app.DiagonalLabel = uilabel(app.OutputGridLayout);
            app.DiagonalLabel.Text = {'Diagonal'; 'Canonical Form'};
            app.Diagonal_ALabel = uilabel(app.OutputGridLayout);
            app.Diagonal_ALabel.Text = 'A = ';
            app.Diagonal_BLabel = uilabel(app.OutputGridLayout);
            app.Diagonal_BLabel.Text = 'B = ';
            app.Diagonal_CLabel = uilabel(app.OutputGridLayout);
            app.Diagonal_CLabel.Text = 'C = ';
            app.Diagonal_DLabel = uilabel(app.OutputGridLayout);
            app.Diagonal_DLabel.Text = 'D = ';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.Observability_Label = uilabel(app.GridLayout3);
            app.Observability_Label.Visible = 'off';
            app.Observability_Label.Text = 'Observable';
            app.Controllability_Label = uilabel(app.GridLayout3);
            app.Controllability_Label.Visible = 'off';
            app.Controllability_Label.Text = 'Controllable';
            app.StepResponsePlot = uiaxes(app.GridLayout);
            title(app.StepResponsePlot, 'Step Response')
            xlabel(app.StepResponsePlot, 'Time')
            ylabel(app.StepResponsePlot, 'Amplitude')
            app.StepResponsePlot.XTick = [0 0.5 1];
            app.StepResponsePlot.YTick = [0 0.5 1];
            app.PoleZeroPlot = uiaxes(app.GridLayout);
            title(app.PoleZeroPlot, 'Poles and Zeros')
            xlabel(app.PoleZeroPlot, 'Real')
            ylabel(app.PoleZeroPlot, 'Imaginary')
            app.PoleZeroPlot.XTick = [0 0.5 1];
            app.PoleZeroPlot.YTick = [0 0.5 1];
            app.StateSpaceTransferFunctionTab = uitab(app.TabGroup);
            app.StateSpaceTransferFunctionTab.Title = 'State Space - Transfer Function';
            app.GridLayout2 = uigridlayout(app.StateSpaceTransferFunctionTab);
            app.StateSpacePanel_2 = uipanel(app.GridLayout2);
            app.StateSpacePanel_2.Title = 'State Space';
            app.GridLayout4 = uigridlayout(app.StateSpacePanel_2);
            app.AEditFieldLabel = uilabel(app.GridLayout4);
            app.AEditFieldLabel.Text = 'A =';
            app.AEditField = uieditfield(app.GridLayout4, 'text');
            app.AEditField.ValueChangingFcn = createCallbackFcn(app, @AEditFieldValueChanging, true);
            app.BEditFieldLabel = uilabel(app.GridLayout4);
            app.BEditFieldLabel.Text = 'B =';
            app.BEditField = uieditfield(app.GridLayout4, 'text');
            app.BEditField.ValueChangingFcn = createCallbackFcn(app, @BEditFieldValueChanging, true);
            app.CEditFieldLabel = uilabel(app.GridLayout4);
            app.CEditFieldLabel.Text = 'C =';
            app.CEditField = uieditfield(app.GridLayout4, 'text');
            app.CEditField.ValueChangingFcn = createCallbackFcn(app, @CEditFieldValueChanging, true);
            app.DLabel = uilabel(app.GridLayout4);
            app.DLabel.Text = {'D ='; ''};
            app.DEditField = uieditfield(app.GridLayout4, 'text');
            app.DEditField.ValueChangingFcn = createCallbackFcn(app, @DEditFieldValueChanging, true);
            app.TransferFunctionPanel_2 = uipanel(app.GridLayout2);
            app.TransferFunctionPanel_2.Title = 'Transfer Function';
            app.GridLayout5 = uigridlayout(app.TransferFunctionPanel_2);
            app.TransferFunctionLabel_2 = uilabel(app.GridLayout5);
            app.TransferFunctionLabel_2.Text = '';
            app.StepResponsePlot_2 = uiaxes(app.GridLayout2);
            title(app.StepResponsePlot_2, 'Step Response')
            xlabel(app.StepResponsePlot_2, 'X')
            ylabel(app.StepResponsePlot_2, 'Y')
            app.PoleZeroPlot_2 = uiaxes(app.GridLayout2);
            title(app.PoleZeroPlot_2, {'Poles and Zeros'; ''})
            xlabel(app.PoleZeroPlot_2, 'X')
            ylabel(app.PoleZeroPlot_2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end